classdef BreathHeart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        EnviromentState      matlab.ui.control.EditField
        EditField_2Label     matlab.ui.control.Label
        BodyState            matlab.ui.control.EditField
        Label_5              matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        BreathRateEditField  matlab.ui.control.NumericEditField
        ButtonStop           matlab.ui.control.Button
        ButtonStart          matlab.ui.control.Button
        HeartRateEditField   matlab.ui.control.NumericEditField
        Label_4              matlab.ui.control.Label
        dataPanel            matlab.ui.container.Panel
        HeartFreAxes         matlab.ui.control.UIAxes
        BreathFreAxes        matlab.ui.control.UIAxes
        HeartWaveAxes        matlab.ui.control.UIAxes
        BreathWaveAxes       matlab.ui.control.UIAxes
        fftDataAxes          matlab.ui.control.UIAxes
        originDataAxes       matlab.ui.control.UIAxes
        uartPanel            matlab.ui.container.Panel
        bandRate_DropDown    matlab.ui.control.DropDown
        Label_2              matlab.ui.control.Label
        uartPort_DropDown    matlab.ui.control.DropDown
        Label                matlab.ui.control.Label
    end

    
    properties (Access = private)
        COM                         % port
        baudRate                    % baudRate
        SERIAL                      % SERIAL
        chirpFlag                   % chirp flag
        recvData_once               % once receive bytes
        recvData_total              % total receive bytes
        samples                     % samples 
        samplesIndex                % samples index
        c                           % light speed
        fc                          % center frequency
        lambda                      % wave length
        chirpSlope                  % chirp slope
        rangeIndex                  % range Index
        fs                          % frequency samples
        freq_bin                    % frequency bin
        orignalInex                 
        fftnumsample
        M                           % 读取脉冲数（帧数）
        rt
        rf
        Rt
        workpush = true
        
        
        %参数初始化
        BreathWinSize         %呼吸存储器的窗长
        HeartWinSize          %心跳存储器的窗长
        Prephased             %上一时刻相位的初始值
        Prephasediff          %用于临时存储上一帧的相位，用于相位差计算
        diffPhaseCorrectionCum %用于相位解缠绕的值连续值
        PHASE_FFT_SIZE       %谱估计长度
        dataPrev1            %用于脉冲去除的初始值设定
        dataPrev2           %用于脉冲去除的初始值设定
        dataCurr 
        MOTIONDETECTORSEMENT   %运动片段检查尺寸
        gFrameCount            %用于片段检测
        MOTIONDETECTIONTHRESH  %运动片段检测阈值
        MOtionDetectionenable  %是否开启运动片段检测，是为1，否为0
        diffbuffHeart          
        diffbuffBreath
        motiondetectionbuff    %运动片段检查尺寸
        %静态杂波去除
        W                      %加权因子
        RemoveStatic           %是否去除静态杂波
        ReStaticbuff           %用于临时存放静态杂波的递推数组
        %目标峰值提取
        startindex   %开始搜索范围9
        endindex     %结束搜索范围27

        %用于求取中位数
        MEDIAN_WINDOW_LENGTH
        pBufferHeartRate
        pBufferBreathingRate

        maxIndexBreathSpect
    end
    
    properties (Access = public)
        X4M200_ReData           % 用于存储雷达实部数据
        X4M200_ImData           % 用于存储雷达虚部数据
    end
    
    methods (Access = private)

            function  HeratBreath_main(app)
                i=0;
            addModuleConnectorPath();
            addpath D:\Mmware-project\BreathDetectionMatch\include;
            addpath D:\Mmware-project\BreathDetectionMatch\lib;
            app.COM = app.uartPort_DropDown.Value;  %get COM port
            Lib = ModuleConnector.Library;
            Lib.libfunctions;
            mc = ModuleConnector.ModuleConnector(app.COM,0);
            xep = mc.get_xep();
            FWID = xep.get_system_info(2);
            switch FWID
                case {'Annapurna','X4M300'}
                    radarApp = mc.get_x4m300();
                    radarApp.set_sensor_mode('stop');
                    radarApp.set_sensor_mode('XEP');
                case 'XEP'
                otherwise
                    error(['Unknown device connected. Returns FirmWareID ' FWID]);
            end
            while xep.peek_message_data_float > 0
                xep.read_message_data_float();
            end
            xep.x4driver_init();
            xep.x4driver_set_downconversion(1);
            xep.x4driver_set_iterations(16);
            xep.x4driver_set_pulsesperstep(10);
            xep.x4driver_set_dac_min(949);
            xep.x4driver_set_dac_max(1100);
            xep.x4driver_set_frame_area_offset(0.18);
            xep.x4driver_set_frame_area(0.4, 5.0);
            [frame_start,frame_stop] = xep.x4driver_get_frame_area();
            xep.x4driver_set_fps(600);
            
            % Create plots within the app.originDataAxes and app.PhaseAxes
            app.originDataAxes.XGrid = 'on';
            app.originDataAxes.YGrid = 'on';
            app.originDataAxes.YLim = [-3*pi/2 3*pi/2];
            bin_length = 8 * 1.5e8/23.328e9;
            range_vector = (frame_start-1e-5):bin_length:(frame_stop+1e-5);
            
             while app.workpush
                [~, data_length, ~, data] = xep.read_message_data_float();
                % Generate IQ vector.
                i_vec = data(1:data_length/2);
                q_vec = data(data_length/2+1:data_length);
                iq_vec = i_vec + 1i*q_vec;
                vdata = zeros(app.M,data_length);
                for i=1:app.M
                    vdata(i,:)=data;                   % 第i帧（脉冲）（快时间）数据
                end 
                idata=vdata(:,1:data_length/2);
                qdata=vdata(:,data_length/2+1:end); 
                app.rt=complex(idata,qdata);                   % 基带复信号
                app.rf = abs(fftshift(fft(app.rt),1));
%                 format shortE % 设置输出格式为短形式的科学计数法
%                 disp(idata);
                mesh(app.fftDataAxes,1:data_length/2,linspace(-app.fs/2,app.fs/2,app.M),app.rf);
                if i ~= 600
                    i = i+1;
                end
%                 if i == 600
%                     i = 0;
%                 end
                % Update data in handles for plotting.
                plot(app.originDataAxes, range_vector, abs(iq_vec));
                app.originDataAxes.YLim = [0 0.05];
                Analysis_Radar_Echo_Signal(app);
                Chest_movement(app);
                breath_movement(app);
                drawnow;
            end
          end

          function Analysis_Radar_Echo_Signal(app)
%                 radardata = [app.X4M200_ReData;app.X4M200_ImData];
% %                 size(radardata)
%                 Length = 182;
%                 data = zeros(app.M,Length);
%                 for i=1:app.M
%                     data(i,:)=radardata;                   % 第i帧（脉冲）（快时间）数据
%                 end 
%                 idata=data(:,1:Length/2);
%                 qdata=data(:,Length/2+1:end); 
%                 app.rt=complex(idata,qdata);                   % 基带复信号
%                 app.rf = abs(fftshift(fft(app.rt),1));
% %                 format shortE % 设置输出格式为短形式的科学计数法
% %                 disp(idata);
%                 mesh(app.fftDataAxes,1:Length/2,linspace(-app.fs/2,app.fs/2,app.M),app.rf);
          end

          function Chest_movement(app)
                [~,I] = max(abs(app.rf(:)));
                Ir = ceil(I/size(app.rf,1));
                t=(0:app.M-1)/app.fs;
                phase=zeros(1,app.M);
                RU=app.c/app.fc/4;
                for i=1:app.M
                   phase(i)=angle(app.rt(i,Ir)); 
                end
                app.Rt=-phase*app.c/2/pi/app.fc/2;                % 体表微动信号
                Rmax=max(abs(app.Rt));
                
                for i=2:app.M                             % 对突变的微动信号做补偿
                  mx=0;
                  if (app.Rt(i)-app.Rt(i-1))<-1.5*RU
                      mx=1;
                  elseif (app.Rt(i)-app.Rt(i-1))>1.5*RU
                      mx=-1;
                  end
                  app.Rt(i)=app.Rt(i)+mx*2*Rmax;
                end
                app.Rt=app.Rt-sum(app.Rt)/app.M;                      % 抑制0频信号分量
                Rf=abs(fft(app.Rt,app.M));
                K=round(app.M/2);
                f=app.fs/app.M*(0:K-1);
                BreathRate = 1;
                app.BreathRateEditField.Value = BreathRate;

                if BreathRate > 0
                    plot(app.BreathWaveAxes, t,app.Rt);
                    app.BreathWaveAxes.YLim = [0 max(app.Rt)+0.0001];
                    plot(app.BreathFreAxes, f,Rf(1:K));
                    app.BreathFreAxes.YLim = [0 0.5];
                end
          end

          function breath_movement(app)
                t=(0:app.M-1)/app.fs;
                %% 欠采样处理
                %根据需要可以进行欠采样处理
                Rt1=app.Rt(1:1:app.M);                           % 欠采样数据
                M1=length(Rt1);                              % 欠采样点数
                K1=round(M1/2);
                df1=app.fs/M1;
                f1=df1*(0:K1-1);
                
                %% 滤除呼吸引起的胸腔壁运动
                Wp=1/app.fs*2;
                Wu=2/app.fs*2;
                window=gausswin(100+1);
                H=fir1(100,[Wp,Wu],window);
                Rt2 = conv(Rt1,H,'same');
                Rf2=(abs(fft(Rt2,M1)));
%                 HeartRate = PeakHeartRate(app,heartfftspectrum,app.maxIndexBreathSpect);
                HeartRate = 1;
                if HeartRate > 0
                    plot(app.HeartWaveAxes, t,Rt2);
                    plot(app.HeartFreAxes, f1 ,Rf2(1:K1));
                end
          end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                fclose(instrfind);
            catch 
            app.COM = serialportlist('available');  %find available port
            app.uartPort_DropDown.Items = flipud(app.COM); %port number assignment dropdown
            app.bandRate_DropDown.Items = {'115200','961200'}; %bandrate assignment dropdown
            end
            %初始化参数
            app.fs = 17;% 脉冲重复周期
            app.c = 3e8;%光速
            app.fc = 7.25e9;%载波频率
            app.M = 600;% 读取脉冲数（帧数）

        end

        % Button pushed function: ButtonStart
        function ButtonStartPushed(app, event)
            HeratBreath_main(app);
%             app.workpush = true;
        end

        % Button pushed function: ButtonStop
        function ButtonStopPushed(app, event)
            app.workpush = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.uartPanel = uipanel(app.UIFigure);
            app.uartPanel.Title = '端口设置';
            app.Label = uilabel(app.uartPanel);
            app.Label.Text = '端口号';
            app.uartPort_DropDown = uidropdown(app.uartPanel);
            app.uartPort_DropDown.Items = {};
            app.uartPort_DropDown.Value = {};
            app.Label_2 = uilabel(app.uartPanel);
            app.Label_2.Text = '波特率';
            app.bandRate_DropDown = uidropdown(app.uartPanel);
            app.bandRate_DropDown.Items = {};
            app.bandRate_DropDown.Value = {};
            app.dataPanel = uipanel(app.UIFigure);
            app.dataPanel.Title = '图像显示';
            app.originDataAxes = uiaxes(app.dataPanel);
            title(app.originDataAxes, '原始数据')
            xlabel(app.originDataAxes, '距离（m）')
            ylabel(app.originDataAxes, '幅度')
            app.fftDataAxes = uiaxes(app.dataPanel);
            title(app.fftDataAxes, '距离维')
            xlabel(app.fftDataAxes, '距离(m)')
            ylabel(app.fftDataAxes, '幅度')
            app.BreathWaveAxes = uiaxes(app.dataPanel);
            title(app.BreathWaveAxes, '呼吸信号时域波形')
            xlabel(app.BreathWaveAxes, '时域窗长')
            ylabel(app.BreathWaveAxes, '信号幅度')
            zlabel(app.BreathWaveAxes, 'Z')
            app.BreathWaveAxes.YTick = 0;
            app.BreathWaveAxes.YTickLabel = {'0'};
            app.HeartWaveAxes = uiaxes(app.dataPanel);
            title(app.HeartWaveAxes, '心跳信号时域波形')
            xlabel(app.HeartWaveAxes, '时域窗长')
            ylabel(app.HeartWaveAxes, '信号幅度')
            zlabel(app.HeartWaveAxes, 'Z')
            app.BreathFreAxes = uiaxes(app.dataPanel);
            title(app.BreathFreAxes, '呼吸信号频域波形')
            xlabel(app.BreathFreAxes, '时域窗长')
            ylabel(app.BreathFreAxes, '信号频率')
            zlabel(app.BreathFreAxes, 'Z')
            app.HeartFreAxes = uiaxes(app.dataPanel);
            title(app.HeartFreAxes, '呼吸信号频域波形')
            xlabel(app.HeartFreAxes, '频域窗长')
            ylabel(app.HeartFreAxes, '信号幅度')
            zlabel(app.HeartFreAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '数据显示';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '心跳率（次/分钟）';
            app.HeartRateEditField = uieditfield(app.Panel, 'numeric');
            app.HeartRateEditField.RoundFractionalValues = 'on';
            app.ButtonStart = uibutton(app.Panel, 'push');
            app.ButtonStart.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartPushed, true);
            app.ButtonStart.Text = '开始检测';
            app.ButtonStop = uibutton(app.Panel, 'push');
            app.ButtonStop.ButtonPushedFcn = createCallbackFcn(app, @ButtonStopPushed, true);
            app.ButtonStop.Text = '停止检测';
            app.BreathRateEditField = uieditfield(app.Panel, 'numeric');
            app.BreathRateEditField.RoundFractionalValues = 'on';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '呼吸率（次/分钟）';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '人体状态';
            app.BodyState = uieditfield(app.Panel, 'text');
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Text = '环境干扰';
            app.EnviromentState = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BreathHeart

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end